classdef GUI_Kinematics < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        PostionLabel           matlab.ui.control.Label
        AnglesLabel            matlab.ui.control.Label
        ZPoseEditField         matlab.ui.control.NumericEditField
        ZPoseEditFieldLabel    matlab.ui.control.Label
        YPoseEditField         matlab.ui.control.NumericEditField
        YPoseEditFieldLabel    matlab.ui.control.Label
        XPoseEditField         matlab.ui.control.NumericEditField
        XPoseEditFieldLabel    matlab.ui.control.Label
        InverseButton          matlab.ui.control.Button
        Theta06EditField       matlab.ui.control.NumericEditField
        Theta06EditFieldLabel  matlab.ui.control.Label
        Theta05EditField       matlab.ui.control.NumericEditField
        Theta05EditFieldLabel  matlab.ui.control.Label
        Theta04EditField       matlab.ui.control.NumericEditField
        Theta04EditFieldLabel  matlab.ui.control.Label
        Theta03EditField       matlab.ui.control.NumericEditField
        Theta03EditFieldLabel  matlab.ui.control.Label
        Theta02EditField       matlab.ui.control.NumericEditField
        Theta02EditFieldLabel  matlab.ui.control.Label
        Theta01EditField       matlab.ui.control.NumericEditField
        Theta01EditFieldLabel  matlab.ui.control.Label
        ForwardButton          matlab.ui.control.Button
        UIAxes                 matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: ForwardButton, Theta01EditField, 
        % ...and 5 other components
        function ForwardButtonPushed(app, event)
            % --- 1. Read Input Angles (Numerical) ---
    % Get values directly from App Designer numeric edit fields.
    % NOTE: Replace 'Theta_1EditField', 'Theta_2EditField', etc., with the
    % actual names of your input components in App Designer.
    try
        th = [
            app.Theta_1EditField.Value;
            app.Theta_2EditField.Value;
            app.Theta_3EditField.Value;
            app.Theta_4EditField.Value;
            app.Theta_5EditField.Value;
            app.Theta_6EditField.Value
        ]' * pi/180; % Convert all angles from degrees to radians
    catch E
        % In case a component name is wrong, show a helpful error
        uialert(app.UIFigure, ['Error reading joint angles. Check component names. Original error: ' E.message], 'Input Error');
        return;
    end

    % --- 2. Define Robot DH Parameters ---
    % These values should match your robot's physical dimensions.
    % Using the parameter names from your code. Please double-check them.
    a1 = 135.3;
    d1 = 37.5;
    d2 = 160;
    d3 = 15;
    a2 = 137.3;
    endeff = 0;
    width = 50; % This parameter was used for a few links.

    % --- 3. Create Robot Model using Peter Corke's Robotics Toolbox ---
    % Define each link using the standard DH parameters: Link('revolute', 'd', d, 'a', a, 'alpha', alpha)
    % The 'offset' parameter is used for joint angle offsets.
    L(1) = Link('revolute', 'd', d1,    'a', a1,    'alpha', pi/2, 'offset', 0);
    L(2) = Link('revolute', 'd', d2,    'a', width, 'alpha', pi,   'offset', pi/2); % Offset of 90 deg (pi/2 rad)
    L(3) = Link('revolute', 'd', d3,    'a', width, 'alpha', -pi/2,'offset', 0);
    L(4) = Link('revolute', 'd', 0,     'a', a2,    'alpha', pi/2, 'offset', 0);
    L(5) = Link('revolute', 'd', 0,     'a', 0,     'alpha', -pi/2,'offset', 0);
    L(6) = Link('revolute', 'd', endeff,'a', 0,     'alpha', 0,    'offset', 0);

    Robot = SerialLink(L, 'name', 'Kuka Clone');

    % --- 4. Calculate Forward Kinematics ---
    % Use the .fkine() method from the toolbox. It returns a 4x4 homogeneous transformation matrix.
    T = Robot.fkine(th);

    % The position vector is in the first 3 elements of the 4th column.
    p = T.t; % This gets the translation vector [x; y; z]

    % --- 5. Display the Final Position ---
    % Update the App Designer UI components with the calculated position.
    % NOTE: Replace 'Pos_XEditField', etc., with your component names.
    app.Pos_XEditField.Value = round(p(1), 3);
    app.Pos_YEditField.Value = round(p(2), 3);
    app.Pos_ZEditField.Value = round(p(3), 3);

    % --- 6. Animate the Robot in the UIAxes ---
    % NOTE: Replace 'UIAxes' with the name of your axes component.
    
    % Generate a smooth trajectory from the zero position to the target
    q_initial = zeros(1, 6); % Start from the home position
    trajectory_steps = 50; % Number of steps in the animation
    trajectory = jtraj(q_initial, th, trajectory_steps);
    
    % Get the Cartesian path for the trajectory to plot the end-effector path
    T_path = Robot.fkine(trajectory);
    path_points = T_path.t; % This is a 3xN matrix of points [x; y; z]

    % Plot the robot and the path
    cla(app.UIAxes); % Clear the axes before drawing
    
    % Plot the robot in its final configuration
    Robot.plot(th, 'ax', app.UIAxes);
    hold(app.UIAxes, 'on'); % Keep the robot plot
    
    % Plot the end-effector path
    plot3(app.UIAxes, path_points(:,1), path_points(:,2), path_points(:,3), 'r', 'LineWidth', 2);
    
    % Improve plot appearance
    grid(app.UIAxes, 'on');
    axis(app.UIAxes, 'equal');
    xlabel(app.UIAxes, 'X (mm)');
    ylabel(app.UIAxes, 'Y (mm)');
    zlabel(app.UIAxes, 'Z (mm)');
    title(app.UIAxes, 'Robot Pose and End-Effector Trajectory');
    hold(app.UIAxes, 'off');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ForwardButton = uibutton(app.UIFigure, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.ForwardButton.Text = 'Forward';
            app.Theta01EditFieldLabel = uilabel(app.UIFigure);
            app.Theta01EditFieldLabel.Text = 'Theta 01';
            app.Theta01EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta01EditField.ValueChangedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.Theta02EditFieldLabel = uilabel(app.UIFigure);
            app.Theta02EditFieldLabel.Text = 'Theta 02';
            app.Theta02EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta02EditField.ValueChangedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.Theta03EditFieldLabel = uilabel(app.UIFigure);
            app.Theta03EditFieldLabel.Text = 'Theta 03';
            app.Theta03EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta03EditField.ValueChangedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.Theta04EditFieldLabel = uilabel(app.UIFigure);
            app.Theta04EditFieldLabel.Text = 'Theta 04';
            app.Theta04EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta04EditField.ValueChangedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.Theta05EditFieldLabel = uilabel(app.UIFigure);
            app.Theta05EditFieldLabel.Text = 'Theta 05';
            app.Theta05EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta05EditField.ValueChangedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.Theta06EditFieldLabel = uilabel(app.UIFigure);
            app.Theta06EditFieldLabel.Text = 'Theta 06';
            app.Theta06EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta06EditField.ValueChangedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.InverseButton = uibutton(app.UIFigure, 'push');
            app.InverseButton.Text = 'Inverse';
            app.XPoseEditFieldLabel = uilabel(app.UIFigure);
            app.XPoseEditFieldLabel.Text = 'X Pose';
            app.XPoseEditField = uieditfield(app.UIFigure, 'numeric');
            app.YPoseEditFieldLabel = uilabel(app.UIFigure);
            app.YPoseEditFieldLabel.Text = 'Y Pose';
            app.YPoseEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZPoseEditFieldLabel = uilabel(app.UIFigure);
            app.ZPoseEditFieldLabel.Text = 'Z Pose';
            app.ZPoseEditField = uieditfield(app.UIFigure, 'numeric');
            app.AnglesLabel = uilabel(app.UIFigure);
            app.AnglesLabel.Text = 'Angles';
            app.PostionLabel = uilabel(app.UIFigure);
            app.PostionLabel.Text = 'Postion';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_Kinematics

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end